classdef DataGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        PrePostButtonGroup       matlab.ui.container.ButtonGroup
        PreButton                matlab.ui.control.RadioButton
        PostButton               matlab.ui.control.RadioButton
        TestOutput               matlab.ui.control.Label
        SessionStageButtonGroup  matlab.ui.container.ButtonGroup
        RestingStagesLabel       matlab.ui.control.Label
        TaskStagesLabel          matlab.ui.control.Label
        RestCloseButton          matlab.ui.control.RadioButton
        RestOpenButton           matlab.ui.control.RadioButton
        InfoRespButton           matlab.ui.control.RadioButton
        InfoButton               matlab.ui.control.RadioButton
        InfoAntiButton           matlab.ui.control.RadioButton
        DecisionButton           matlab.ui.control.RadioButton
        LoadButton               matlab.ui.control.Button
        LoadingStatusLabel       matlab.ui.control.Label
        SessionIdxDropDown       matlab.ui.control.DropDown
        SessionTypeDropDown      matlab.ui.control.DropDown
        SessionDropDownLabel     matlab.ui.control.Label
        UIAxes                   matlab.ui.control.UIAxes
    end

    properties (Access = private)
        % constants
        all_session_types = {'Muscimol', 'Saline', 'SimRec'};

        % file path constants/variables
        root_path = './'
        kernel = 'Delta';
        reg = 'L2=2';
        epoch = '2500';
    
        % status variables
        file_found = false;
        file_loaded = false;

        % option variables
        session_type = '';
        session_idx = '-';
        session_prepost = '';
        session_stage = '';

        % data
        J_mat = NaN;

    end
    
    methods (Access = private)
        function SyncVariables(app)
            app.session_type = app.SessionTypeDropDown.Value;
            app.session_idx = app.SessionIdxDropDown.Value;
            app.session_prepost = app.PrePostButtonGroup.SelectedObject.Text;
            app.session_stage = app.SessionStageButtonGroup.SelectedObject.Text;
        end

        function UpdateSessionIdxSelection(app)
            session_idx_list = [];
            switch app.session_type
                case 'Muscimol'
                    session_idx_list = 1:10;
                case 'Saline'
                    session_idx_list = 1:5;
                case 'SimRec'
                    session_idx_list = 1:5;
            end

            session_idx_cell = {'-'};
            for i = session_idx_list
                session_idx_cell{end+1} = int2str(i);
            end

            app.SessionIdxDropDown.Items = session_idx_cell;
            app.SessionIdxDropDown.Value = '-';
            app.session_idx = '-';
            app.UpdateSessionStageSelection();
        end

        function UpdateSessionStageSelection(app)
            if strcmp(app.session_idx, '-')
                app.PrePostButtonGroup.Enable = "off";
                app.SessionStageButtonGroup.Enable = "off";
            else
                app.PrePostButtonGroup.Enable = "on";
                app.SessionStageButtonGroup.Enable = "on";
            end
        end

        function UpdateLoadButton(app)
            if strcmp(app.session_idx, '-')
                app.LoadButton.Enable = "off";
                app.LoadingStatusLabel.Text = "Select a Session.";
            else
                % find file
                app.file_found = false;
                if strcmp(app.session_prepost, 'Pre')
                    session_stage_full = [app.session_type, 'Pre', app.session_stage, '_full'];
                else
                    session_stage_full = [app.session_type, 'Post', app.session_stage, '_cortex'];
                end

                file_path = [app.root_path, 'GLM_model/', session_stage_full,...
                    '/GLM_', session_stage_full, '_', app.session_idx, '_',...
                    app.kernel, '_0_', app.reg, '_', app.epoch, '.mat'];
                app.TestOutput.Text = file_path;
                app.file_found = isfile(file_path);

                if app.file_found
                    app.LoadButton.Enable = "on";
                    app.LoadingStatusLabel.Text = "Ready to Load.";
                else
                    app.LoadButton.Enable = "off";
                    app.LoadingStatusLabel.Text = "File Not Found.";
                end
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Initialize session types
            app.SessionTypeDropDown.Items = app.all_session_types;
            app.SessionTypeDropDown.Value = app.all_session_types{1};
            app.session_type = app.all_session_types{1};

            app.UpdateSessionIdxSelection();
            app.UpdateLoadButton();
        end

        % Value changed function: SessionTypeDropDown
        function SessionTypeDropDownValueChanged(app, event)
            app.SyncVariables();
            app.UpdateSessionIdxSelection();
            app.UpdateLoadButton();
        end

        % Value changed function: SessionIdxDropDown
        function SessionIdxDropDownValueChanged(app, event)
            app.SyncVariables();
            app.UpdateSessionStageSelection();
            app.UpdateLoadButton();
        end

        % Selection changed function: PrePostButtonGroup, 
        % ...and 1 other component
        function SessionStageButtonGroupSelectionChanged(app, event)
            app.SyncVariables();
            app.UpdateLoadButton();
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.SessionDropDownLabel = uilabel(app.UIFigure);
            app.SessionDropDownLabel.Text = 'Session';
            app.SessionTypeDropDown = uidropdown(app.UIFigure);
            app.SessionTypeDropDown.Items = {'Muscimol', 'Saline', 'SimRec'};
            app.SessionTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @SessionTypeDropDownValueChanged, true);
            app.SessionTypeDropDown.Value = 'Muscimol';
            app.SessionIdxDropDown = uidropdown(app.UIFigure);
            app.SessionIdxDropDown.Items = {'1'};
            app.SessionIdxDropDown.ValueChangedFcn = createCallbackFcn(app, @SessionIdxDropDownValueChanged, true);
            app.SessionIdxDropDown.Value = '1';
            app.LoadingStatusLabel = uilabel(app.UIFigure);
            app.LoadingStatusLabel.Text = 'Select a Session.';
            app.LoadButton = uibutton(app.UIFigure, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Enable = 'off';
            app.LoadButton.Text = 'Load';
            app.SessionStageButtonGroup = uibuttongroup(app.UIFigure);
            app.SessionStageButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @SessionStageButtonGroupSelectionChanged, true);
            app.SessionStageButtonGroup.Enable = 'off';
            app.SessionStageButtonGroup.Title = 'Stage';
            app.DecisionButton = uiradiobutton(app.SessionStageButtonGroup);
            app.DecisionButton.Text = 'Decision';
            app.DecisionButton.Value = true;
            app.InfoAntiButton = uiradiobutton(app.SessionStageButtonGroup);
            app.InfoAntiButton.Text = 'InfoAnti';
            app.InfoButton = uiradiobutton(app.SessionStageButtonGroup);
            app.InfoButton.Text = 'Info';
            app.InfoRespButton = uiradiobutton(app.SessionStageButtonGroup);
            app.InfoRespButton.Text = 'InfoResp';
            app.RestOpenButton = uiradiobutton(app.SessionStageButtonGroup);
            app.RestOpenButton.Text = 'RestOpen';
            app.RestCloseButton = uiradiobutton(app.SessionStageButtonGroup);
            app.RestCloseButton.Text = 'RestClose';
            app.TaskStagesLabel = uilabel(app.SessionStageButtonGroup);
            app.TaskStagesLabel.Text = 'Task Stages';
            app.RestingStagesLabel = uilabel(app.SessionStageButtonGroup);
            app.RestingStagesLabel.Text = 'Resting Stages';
            app.TestOutput = uilabel(app.UIFigure);
            app.TestOutput.Text = 'Path';
            app.PrePostButtonGroup = uibuttongroup(app.UIFigure);
            app.PrePostButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @SessionStageButtonGroupSelectionChanged, true);
            app.PrePostButtonGroup.Enable = 'off';
            app.PrePostButtonGroup.Title = 'Pre/Post';
            app.PostButton = uiradiobutton(app.PrePostButtonGroup);
            app.PostButton.Text = 'Post';
            app.PreButton = uiradiobutton(app.PrePostButtonGroup);
            app.PreButton.Text = 'Pre';
            app.PreButton.Value = true;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DataGUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end